classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        UIAxes             matlab.ui.control.UIAxes
        Button             matlab.ui.control.Button
        RmmEditFieldLabel  matlab.ui.control.Label
        RmmEditField       matlab.ui.control.NumericEditField
        rmmEditFieldLabel  matlab.ui.control.Label
        rmmEditField       matlab.ui.control.NumericEditField
        LmmLabel           matlab.ui.control.Label
        LmmEditField       matlab.ui.control.NumericEditField
        lmmLabel           matlab.ui.control.Label
        lmmEditField       matlab.ui.control.NumericEditField
        TextAreaLabel      matlab.ui.control.Label
        TextArea           matlab.ui.control.TextArea
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes.YLim = [-300 300];
            app.UIAxes.XLim = [-300 300];
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            global r R l L
            R = app.RmmEditField.Value;%Радиус вписанной окружности верхнего основания
            r = app.rmmEditField.Value;%Радиус вписанной окружности нижней платформы
            l = app.lmmEditField.Value; % Длина нижнего рычага (предплечья)
            L =  app.LmmEditField.Value; % Длина верхнего рычага (плеча)
            
            hold(app.UIAxes, 'on')
            grid(app.UIAxes,'on')
            rotate3d(app.UIAxes,'on');
            [A, n] = WorkSpace();
            app.UIAxes.cla;
            plot3(app.UIAxes, A(:,1), A(:,2), A(:,3),'.','color', [0.5 0 0.5], 'MarkerSize',5)
            axis(app.UIAxes, 'equal')
            zoom(app.UIAxes,'on')
            %view(app.UIAxes,90,0)
            hold(app.UIAxes, 'off')
            [sum, middleZ] = Cuboid();
            app.TextArea.Value = "В рабочую область можно поместить куб размером            "+num2str(2*sum)+" x "+num2str(2*sum)+" x "+num2str(2*sum)+" мм";
            app.TextArea.Value = app.TextArea.Value + "              Основание куба на высоте " + num2str(middleZ-sum) + " мм";
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Рабочая область дельта-робота')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Запустить построение!';
            app.RmmEditFieldLabel = uilabel(app.UIFigure);
            app.RmmEditFieldLabel.Text = 'Верхний радиус R, mm';
            app.RmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.RmmEditField.Value = 80;
            app.rmmEditFieldLabel = uilabel(app.UIFigure);
            app.rmmEditFieldLabel.Text = 'Нижний радиус r, mm';
            app.rmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.rmmEditField.Value = 25;
            app.LmmLabel = uilabel(app.UIFigure);
            app.LmmLabel.Text = 'Длина плеча L, mm';
            app.LmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.LmmEditField.Value = 110;
            app.lmmLabel = uilabel(app.UIFigure);
            app.lmmLabel.Text = 'Длина локтя l, mm';
            app.lmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.lmmEditField.Value = 225.5;
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Результаты';
            app.TextArea = uitextarea(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end